classdef RootFinder_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RootFinderUIFigure           matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ReadMenu                     matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        InputsPanel                  matlab.ui.container.Panel
        SolveButton                  matlab.ui.control.Button
        FunctionFxEditFieldLabel     matlab.ui.control.Label
        FunctionFxEditField          matlab.ui.control.EditField
        MethodDropDownLabel          matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        PercisionEditFieldLabel      matlab.ui.control.Label
        PercisionEditField           matlab.ui.control.NumericEditField
        GxInput_Label                matlab.ui.control.Label
        GxInput                      matlab.ui.control.EditField
        XlLabel                      matlab.ui.control.Label
        XlInput                      matlab.ui.control.NumericEditField
        XuEditField_2Label           matlab.ui.control.Label
        XuInput                      matlab.ui.control.NumericEditField
        Xi1Input_Label               matlab.ui.control.Label
        Xi1Input                     matlab.ui.control.NumericEditField
        XiInput_Label                matlab.ui.control.Label
        XiInput                      matlab.ui.control.NumericEditField
        OutputsPanel                 matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        AllIterationsLabel           matlab.ui.control.Label
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        RootEditFieldLabel           matlab.ui.control.Label
        RootEditField                matlab.ui.control.NumericEditField
        PercisionEditField_2Label    matlab.ui.control.Label
        PercisionEditField_2         matlab.ui.control.NumericEditField
        ExcutionTimeEditFieldLabel   matlab.ui.control.Label
        ExcutionTimeEditField        matlab.ui.control.NumericEditField
        Tab2                         matlab.ui.container.Tab
    end

    methods (Access = private)

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            switch value
                case 'Bisection'
                    app.XlLabel.Enable = 'on';
                    app.XlInput.Enable = 'on';
                    app.XuEditField_2Label.Enable = 'on';
                    app.XuInput.Enable = 'on';
                    app.Xi1Input_Label.Enable = 'off';
                    app.Xi1Input.Enable = 'off';
                    app.XiInput_Label.Enable = 'off';
                    app.XiInput.Enable = 'off';
                    app.GxInput_Label.Enable = 'off';
                    app.GxInput.Enable = 'off';
                    app.UITable.ColumnName = {'xl', 'f(xl)', 'xu', 'f(xu)', 'xr', 'f(xr)', 'εa'};
                    app.UITable.ColumnFormat = {'bank', 'bank', 'bank', 'bank', 'bank', 'bank', 'bank'};
                    app.UITable.Data = ['','','','','','',''];
                case 'False Position'
                    app.XlLabel.Enable = 'on';
                    app.XlInput.Enable = 'on';
                    app.XuEditField_2Label.Enable = 'on';
                    app.XuInput.Enable = 'on';
                    app.Xi1Input_Label.Enable = 'off';
                    app.Xi1Input.Enable = 'off';
                    app.XiInput_Label.Enable = 'off';
                    app.XiInput.Enable = 'off';
                    app.GxInput_Label.Enable = 'off';
                    app.GxInput.Enable = 'off';
                    app.UITable.ColumnName = {'xl', 'f(xl)', 'xu', 'f(xu)', 'xr', 'f(xr)', 'εa'};
                    app.UITable.ColumnFormat = {'bank', 'bank', 'bank', 'bank', 'bank', 'bank', 'bank'};
                    app.UITable.Data = ['','','','','','',''];
                case 'Fixed Point'
                    app.XlLabel.Enable = 'off';
                    app.XlInput.Enable = 'off';
                    app.XuEditField_2Label.Enable = 'off';
                    app.XuInput.Enable = 'off';
                    app.Xi1Input_Label.Enable = 'off';
                    app.Xi1Input.Enable = 'off';
                    app.XiInput_Label.Enable = 'on';
                    app.XiInput.Enable = 'on';
                    app.GxInput_Label.Enable = 'on';
                    app.GxInput.Enable = 'on';
                    app.UITable.ColumnName = {'xi', 'xr', 'εa'};
                    app.UITable.ColumnFormat = {'long', 'long', 'long'};
                    app.UITable.Data = ['','',''];
                case 'Newton Raphson'
                    app.XlLabel.Enable = 'off';
                    app.XlInput.Enable = 'off';
                    app.XuEditField_2Label.Enable = 'off';
                    app.XuInput.Enable = 'off';
                    app.Xi1Input_Label.Enable = 'off';
                    app.Xi1Input.Enable = 'off';
                    app.XiInput_Label.Enable = 'off';
                    app.XiInput.Enable = 'on';
                    app.GxInput_Label.Enable = 'on';
                    app.GxInput.Enable = 'off';
                    app.UITable.ColumnName = {'xi', 'f(xi)', 'f''(xi)', 'xr', 'εa'};
                    app.UITable.ColumnFormat = {'bank', 'bank', 'bank', 'bank', 'bank'};
                    app.UITable.Data = ['','','','',''];
                case 'Secant'
                    app.XlLabel.Enable = 'off';
                    app.XlInput.Enable = 'off';
                    app.XuEditField_2Label.Enable = 'off';
                    app.XuInput.Enable = 'off';
                    app.Xi1Input_Label.Enable = 'on';
                    app.Xi1Input.Enable = 'on';
                    app.XiInput_Label.Enable = 'on';
                    app.XiInput.Enable = 'on';
                    app.GxInput_Label.Enable = 'off';
                    app.GxInput.Enable = 'off';
                    app.UITable.ColumnName = {'xi-1', 'f(xi-1)', 'xi', 'f(xi)', 'xr', 'εa'};
                    app.UITable.ColumnFormat = {'bank', 'bank', 'bank', 'bank', 'bank', 'bank'};
                    app.UITable.Data = ['','','','','',''];
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Check if f(x) is empty or is in incorrect format
            try
                func = app.FunctionFxEditField.Value;
                if (isempty(func))
                    errordlg('Please enter f(x)');
                    return;
                end
                f = str2func(['@(x) ' func]);
            catch ex
                errordlg(ex.message);
                return;
            end
            value = app.MethodDropDown.Value;
            switch value
                case 'Bisection'
                    try
                    [iterations, root, data, timeElapsed] = bisection(f, app.XlInput.Value, app.XuInput.Value, app.PercisionEditField.Value, app.MaxIterationsEditField.Value);
                    catch
                        return;
                    end 
                case 'False Position'
                    try
                    [iterations,root, data, timeElapsed] = falsePosition(f, app.XlInput.Value, app.XuInput.Value, app.PercisionEditField.Value, app.MaxIterationsEditField.Value);
                    catch
                        return;
                    end
                case 'Fixed Point'
                    try
                        func = app.GxInput.Value;
                        if (isempty(func))
                            errordlg('Please enter g(x)');
                            return;
                        end
                        g = str2func(['@(x) ' func]);
                    catch ex
                        errordlg(ex.message);
                        return;
                    end  
                    [iterations,root, data, timeElapsed] = fixedPoint(f, g, app.XiInput.Value, app.PercisionEditField.Value, app.MaxIterationsEditField.Value);
                case 'Newton Raphson'
                    [iterations,root, data, timeElapsed] = newtonRaphson(f, app.XiInput.Value, app.PercisionEditField.Value, app.MaxIterationsEditField.Value);
                case 'Secant'    
                    [iterations,root, data, timeElapsed] = secant(f, app.Xi1Input.Value, app.XiInput.Value, app.PercisionEditField.Value, app.MaxIterationsEditField.Value);
            end
            app.UITable.Data = data;
            app.IterationsEditField.Value = iterations;
            app.RootEditField.Value = root;
            app.PercisionEditField_2.Value = data(end);
            app.ExcutionTimeEditField.Value = timeElapsed;
        end

        % Menu selected function: ReadMenu
        function ReadMenuSelected(app, event)
            [error, method, f, g, x, epsilon, maxNumberOfIterations] = readfile();
            try
                if (error == 0)
                    app.MethodDropDown.Value = method;
                    app.FunctionFxEditField.Value = f;
                    app.GxInput.Value = g;
                    xInput = str2num(x);
                    switch method
                        case 'Bisection'
                            app.XlInput.Value = xInput(1);
                            app.XuInput.Value = xInput(2);
                        case 'False Position'
                            app.XlInput.Value = xInput(1);
                            app.XuInput.Value = xInput(2);
                        case 'Fixed Point'
                            app.XiInput.Value = xInput(1);
                        case 'Newton Raphson'
                            app.XiInput.Value = xInput(1);
                        case 'Secant'
                            app.Xi1Input.Value = xInput(1);
                            app.XiInput.Value = xInput(2);
                    end
                    app.MaxIterationsEditField.Value = str2double(maxNumberOfIterations);
                    app.PercisionEditField.Value = str2double(epsilon);
                else
                    errordlg(error);
                end
            catch ex
                errordlg(ex.message);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RootFinderUIFigure = uifigure;
            app.RootFinderUIFigure.Name = 'Root Finder';
            app.FileMenu = uimenu(app.RootFinderUIFigure);
            app.FileMenu.Text = 'File';
            app.ReadMenu = uimenu(app.FileMenu);
            app.ReadMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadMenuSelected, true);
            app.ReadMenu.Text = 'Read';
            app.TabGroup = uitabgroup(app.RootFinderUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.InputsPanel = uipanel(app.Tab);
            app.InputsPanel.Title = ' Inputs';
            app.SolveButton = uibutton(app.InputsPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.FunctionFxEditFieldLabel = uilabel(app.InputsPanel);
            app.FunctionFxEditFieldLabel.Text = 'Function F(x)';
            app.FunctionFxEditField = uieditfield(app.InputsPanel, 'text');
            app.MethodDropDownLabel = uilabel(app.InputsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.InputsPanel);
            app.MethodDropDown.Items = {'Bisection', 'False Position', 'Fixed Point', 'Newton Raphson', 'Secant'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Bisection';
            app.MaxIterationsEditFieldLabel = uilabel(app.InputsPanel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MaxIterationsEditField.Limits = [1 Inf];
            app.MaxIterationsEditField.Value = 50;
            app.PercisionEditFieldLabel = uilabel(app.InputsPanel);
            app.PercisionEditFieldLabel.Text = 'Percision';
            app.PercisionEditField = uieditfield(app.InputsPanel, 'numeric');
            app.PercisionEditField.Limits = [0 Inf];
            app.PercisionEditField.Value = 1e-05;
            app.GxInput_Label = uilabel(app.InputsPanel);
            app.GxInput_Label.Enable = 'off';
            app.GxInput_Label.Text = 'G(x)';
            app.GxInput = uieditfield(app.InputsPanel, 'text');
            app.GxInput.Enable = 'off';
            app.XlLabel = uilabel(app.InputsPanel);
            app.XlLabel.Text = 'Xl  ';
            app.XlInput = uieditfield(app.InputsPanel, 'numeric');
            app.XuEditField_2Label = uilabel(app.InputsPanel);
            app.XuEditField_2Label.Text = 'Xu';
            app.XuInput = uieditfield(app.InputsPanel, 'numeric');
            app.XuInput.Value = 1;
            app.Xi1Input_Label = uilabel(app.InputsPanel);
            app.Xi1Input_Label.Enable = 'off';
            app.Xi1Input_Label.Text = 'Xi-1';
            app.Xi1Input = uieditfield(app.InputsPanel, 'numeric');
            app.Xi1Input.Enable = 'off';
            app.Xi1Input.Value = 1;
            app.XiInput_Label = uilabel(app.InputsPanel);
            app.XiInput_Label.Enable = 'off';
            app.XiInput_Label.Text = 'Xi  ';
            app.XiInput = uieditfield(app.InputsPanel, 'numeric');
            app.XiInput.Enable = 'off';
            app.OutputsPanel = uipanel(app.Tab);
            app.OutputsPanel.Title = ' Outputs';
            app.UITable = uitable(app.OutputsPanel);
            app.UITable.ColumnName = {'xl'; 'f(xl)'; 'xu'; 'f(xu)'; 'xr'; 'f(xr)'; 'εa'};
            app.AllIterationsLabel = uilabel(app.OutputsPanel);
            app.AllIterationsLabel.Text = 'All Iterations';
            app.IterationsEditFieldLabel = uilabel(app.OutputsPanel);
            app.IterationsEditFieldLabel.Text = '# Iterations';
            app.IterationsEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.IterationsEditField.Editable = 'off';
            app.RootEditFieldLabel = uilabel(app.OutputsPanel);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.RootEditField.ValueDisplayFormat = '%.10f';
            app.RootEditField.Editable = 'off';
            app.PercisionEditField_2Label = uilabel(app.OutputsPanel);
            app.PercisionEditField_2Label.Text = 'Percision';
            app.PercisionEditField_2 = uieditfield(app.OutputsPanel, 'numeric');
            app.PercisionEditField_2.ValueDisplayFormat = '%.7f';
            app.PercisionEditField_2.Editable = 'off';
            app.ExcutionTimeEditFieldLabel = uilabel(app.OutputsPanel);
            app.ExcutionTimeEditFieldLabel.Text = 'Excution Time';
            app.ExcutionTimeEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ExcutionTimeEditField.ValueDisplayFormat = '%.7f';
            app.ExcutionTimeEditField.Editable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RootFinder_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RootFinderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RootFinderUIFigure)
        end
    end
end