classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RootFinderUI                 matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ReadMenu                     matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        inputPanel                   matlab.ui.container.Panel
        TrapModeCheckBox             matlab.ui.control.CheckBox
        SolveButton                  matlab.ui.control.Button
        FunctionFxEditFieldLabel     matlab.ui.control.Label
        FunctionFxEditField          matlab.ui.control.EditField
        MethodDropDownLabel          matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        PercisionEditFieldLabel      matlab.ui.control.Label
        PercisionEditField           matlab.ui.control.NumericEditField
        GxInput_Label                matlab.ui.control.Label
        GxInput                      matlab.ui.control.EditField
        XuEditField_2Label           matlab.ui.control.Label
        XuInput                      matlab.ui.control.NumericEditField
        XlLabel_2                    matlab.ui.control.Label
        XlInput                      matlab.ui.control.NumericEditField
        Xi1Input_Label               matlab.ui.control.Label
        Xi1Input                     matlab.ui.control.NumericEditField
        XiInput_Label                matlab.ui.control.Label
        XiInput                      matlab.ui.control.NumericEditField
        outputPanel                  matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        AllIterationsLabel           matlab.ui.control.Label
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        RootEditFieldLabel           matlab.ui.control.Label
        RootEditField                matlab.ui.control.NumericEditField
        PercisionEditField_2Label    matlab.ui.control.Label
        PercisionEditField_2         matlab.ui.control.NumericEditField
        ExcutionTimeEditFieldLabel   matlab.ui.control.Label
        ExcutionTimeEditField        matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if value == "Fixed Point"
                app.GxInput.Enable = true;
                app.GxInput_Label.Enable = true;
            else
                app.GxInput.Enable = false;
                app.GxInput_Label.Enable = false;
            end
            
            if value == "Newton Raphson"
                app.XiInput.Enable = true;
                app.XiInput_Label.Enable = true;
            elseif value == "Secant"
                app.XiInput.Enable = true;
                app.XiInput_Label.Enable = true;
                app.Xi1Input.Enable = true;
                app.Xi1Input_Label.Enable = true;
            else
                app.XiInput.Enable = false;
                app.XiInput_Label.Enable = false;
                app.Xi1Input.Enable = false;
                app.Xi1Input_Label.Enable = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RootFinderUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {394, 394};
                app.GridLayout.ColumnWidth = {'1x'};
                app.outputPanel.Layout.Row = 2;
                app.outputPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {284, '1x'};
                app.outputPanel.Layout.Row = 1;
                app.outputPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RootFinderUI = uifigure('Visible', 'off');
            app.RootFinderUI.Name = 'Root Finder';
            app.RootFinderUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.RootFinderUI);
            app.FileMenu.Text = 'File';
            app.ReadMenu = uimenu(app.FileMenu);
            app.ReadMenu.Text = 'Read';
            app.GridLayout = uigridlayout(app.RootFinderUI);
            app.inputPanel = uipanel(app.GridLayout);
            app.inputPanel.Title = ' Inputs';
            app.TrapModeCheckBox = uicheckbox(app.inputPanel);
            app.TrapModeCheckBox.Text = 'Trap Mode';
            app.SolveButton = uibutton(app.inputPanel, 'push');
            app.SolveButton.Text = 'Solve';
            app.FunctionFxEditFieldLabel = uilabel(app.inputPanel);
            app.FunctionFxEditFieldLabel.Text = 'Function F(x)';
            app.FunctionFxEditField = uieditfield(app.inputPanel, 'text');
            app.MethodDropDownLabel = uilabel(app.inputPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.inputPanel);
            app.MethodDropDown.Items = {'Bisection', 'False Position', 'Fixed Point', 'Newton Raphson', 'Secant'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Bisection';
            app.MaxIterationsEditFieldLabel = uilabel(app.inputPanel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.inputPanel, 'numeric');
            app.MaxIterationsEditField.Limits = [1 Inf];
            app.MaxIterationsEditField.Value = 50;
            app.PercisionEditFieldLabel = uilabel(app.inputPanel);
            app.PercisionEditFieldLabel.Text = 'Percision';
            app.PercisionEditField = uieditfield(app.inputPanel, 'numeric');
            app.PercisionEditField.Limits = [0 Inf];
            app.PercisionEditField.Value = 1e-05;
            app.GxInput_Label = uilabel(app.inputPanel);
            app.GxInput_Label.Enable = 'off';
            app.GxInput_Label.Text = 'G(x)';
            app.GxInput = uieditfield(app.inputPanel, 'text');
            app.GxInput.Enable = 'off';
            app.XuEditField_2Label = uilabel(app.inputPanel);
            app.XuEditField_2Label.Text = 'Xu';
            app.XuInput = uieditfield(app.inputPanel, 'numeric');
            app.XuInput.Value = 1;
            app.XlLabel_2 = uilabel(app.inputPanel);
            app.XlLabel_2.Text = 'Xl  ';
            app.XlInput = uieditfield(app.inputPanel, 'numeric');
            app.Xi1Input_Label = uilabel(app.inputPanel);
            app.Xi1Input_Label.Enable = 'off';
            app.Xi1Input_Label.Text = 'Xi-1';
            app.Xi1Input = uieditfield(app.inputPanel, 'numeric');
            app.Xi1Input.Enable = 'off';
            app.Xi1Input.Value = 1;
            app.XiInput_Label = uilabel(app.inputPanel);
            app.XiInput_Label.Enable = 'off';
            app.XiInput_Label.Text = 'Xi  ';
            app.XiInput = uieditfield(app.inputPanel, 'numeric');
            app.XiInput.Enable = 'off';
            app.outputPanel = uipanel(app.GridLayout);
            app.outputPanel.Title = ' Outputs';
            app.UITable = uitable(app.outputPanel);
            app.UITable.ColumnName = {'#'; 'Xl'; 'Xu'; 'Xr'; 'f(Xl)'; 'f(Xu)'; 'εa'};
            app.UITable.RowName = {};
            app.AllIterationsLabel = uilabel(app.outputPanel);
            app.AllIterationsLabel.Text = 'All Iterations';
            app.IterationsEditFieldLabel = uilabel(app.outputPanel);
            app.IterationsEditFieldLabel.Text = '# Iterations';
            app.IterationsEditField = uieditfield(app.outputPanel, 'numeric');
            app.IterationsEditField.Editable = 'off';
            app.RootEditFieldLabel = uilabel(app.outputPanel);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.outputPanel, 'numeric');
            app.RootEditField.Editable = 'off';
            app.PercisionEditField_2Label = uilabel(app.outputPanel);
            app.PercisionEditField_2Label.Text = 'Percision';
            app.PercisionEditField_2 = uieditfield(app.outputPanel, 'numeric');
            app.PercisionEditField_2.Editable = 'off';
            app.ExcutionTimeEditFieldLabel = uilabel(app.outputPanel);
            app.ExcutionTimeEditFieldLabel.Text = 'Excution Time';
            app.ExcutionTimeEditField = uieditfield(app.outputPanel, 'numeric');
            app.ExcutionTimeEditField.Editable = 'off';
            % Show the figure after all components are created
            app.RootFinderUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RootFinderUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RootFinderUI)
        end
    end
end