classdef rootfinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RootFinderUIFigure           matlab.ui.Figure
        InputsPanel                  matlab.ui.container.Panel
        MethodDropDownLabel          matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        FxEditFieldLabel             matlab.ui.control.Label
        FxEditField                  matlab.ui.control.EditField
        TerminationConditionsPanel   matlab.ui.container.Panel
        EpsilonEditFieldLabel        matlab.ui.control.Label
        EpsilonEditField             matlab.ui.control.NumericEditField
        NumberOfIterationsEditFieldLabel  matlab.ui.control.Label
        NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        BrowseButton                 matlab.ui.control.Button
        GxEditFieldLabel             matlab.ui.control.Label
        GxEditField                  matlab.ui.control.EditField
        XValueLowerUpperXiEditFieldLabel  matlab.ui.control.Label
        XValueLowerUpperXiEditField  matlab.ui.control.EditField
        TablePanel                   matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        OutputPanel                  matlab.ui.container.Panel
        ApproximateRootLabel         matlab.ui.control.Label
        ApproximateRootEditField     matlab.ui.control.EditField
        NumberofiterationsLabel      matlab.ui.control.Label
        NumberofiterationsEditField  matlab.ui.control.EditField
        ExecutiontimeLabel           matlab.ui.control.Label
        ExecutiontimeEditField       matlab.ui.control.EditField
        PrecisionLabel               matlab.ui.control.Label
        PrecisionEditField           matlab.ui.control.EditField
        FindRootButton               matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if (strcmp(value, 'Fixed Point'))
                app.GxEditField.Enable = 'on';
                app.GxEditFieldLabel.Enable = 'on';
            else
                app.GxEditField.Enable = 'off';
                app.GxEditFieldLabel.Enable = 'off';
            end
        end

        % Button pushed function: FindRootButton
        function FindRootButtonPushed(app, event)
            value = app.XValueLowerUpperXiEditField.Value;
            initialGuess = str2double(value);
            fprintf("%s",value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RootFinderUIFigure = uifigure;
            app.RootFinderUIFigure.Name = 'Root Finder';
            app.InputsPanel = uipanel(app.RootFinderUIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.MethodDropDownLabel = uilabel(app.InputsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.InputsPanel);
            app.MethodDropDown.Items = {'Bisection', 'False Position', 'Fixed Point', 'Newton Raphson', 'Secant'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Bisection';
            app.FxEditFieldLabel = uilabel(app.InputsPanel);
            app.FxEditFieldLabel.Text = 'F(x)';
            app.FxEditField = uieditfield(app.InputsPanel, 'text');
            app.TerminationConditionsPanel = uipanel(app.InputsPanel);
            app.TerminationConditionsPanel.Title = 'Termination Conditions';
            app.EpsilonEditFieldLabel = uilabel(app.TerminationConditionsPanel);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.TerminationConditionsPanel, 'numeric');
            app.EpsilonEditField.Value = 1e-05;
            app.NumberOfIterationsEditFieldLabel = uilabel(app.TerminationConditionsPanel);
            app.NumberOfIterationsEditFieldLabel.Text = 'Number Of Iterations';
            app.NumberOfIterationsEditField = uieditfield(app.TerminationConditionsPanel, 'numeric');
            app.NumberOfIterationsEditField.Value = 50;
            app.BrowseButton = uibutton(app.InputsPanel, 'push');
            app.BrowseButton.Icon = 'icons8-search-26.png';
            app.BrowseButton.Text = 'Browse';
            app.GxEditFieldLabel = uilabel(app.InputsPanel);
            app.GxEditFieldLabel.Enable = 'off';
            app.GxEditFieldLabel.Text = 'G(x)';
            app.GxEditField = uieditfield(app.InputsPanel, 'text');
            app.GxEditField.Enable = 'off';
            app.XValueLowerUpperXiEditFieldLabel = uilabel(app.InputsPanel);
            app.XValueLowerUpperXiEditFieldLabel.Text = 'X Value ([Lower Upper],Xi)';
            app.XValueLowerUpperXiEditField = uieditfield(app.InputsPanel, 'text');
            app.TablePanel = uipanel(app.RootFinderUIFigure);
            app.TablePanel.Title = 'Table';
            app.UITable = uitable(app.TablePanel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.OutputPanel = uipanel(app.RootFinderUIFigure);
            app.OutputPanel.Title = 'Output';
            app.ApproximateRootLabel = uilabel(app.OutputPanel);
            app.ApproximateRootLabel.Text = 'Approximate Root:';
            app.ApproximateRootEditField = uieditfield(app.OutputPanel, 'text');
            app.ApproximateRootEditField.Editable = 'off';
            app.NumberofiterationsLabel = uilabel(app.OutputPanel);
            app.NumberofiterationsLabel.Text = 'Number of iterations:';
            app.NumberofiterationsEditField = uieditfield(app.OutputPanel, 'text');
            app.NumberofiterationsEditField.Editable = 'off';
            app.ExecutiontimeLabel = uilabel(app.OutputPanel);
            app.ExecutiontimeLabel.Text = 'Execution time:';
            app.ExecutiontimeEditField = uieditfield(app.OutputPanel, 'text');
            app.ExecutiontimeEditField.Editable = 'off';
            app.PrecisionLabel = uilabel(app.OutputPanel);
            app.PrecisionLabel.Text = 'Precision:';
            app.PrecisionEditField = uieditfield(app.OutputPanel, 'text');
            app.PrecisionEditField.Editable = 'off';
            app.FindRootButton = uibutton(app.RootFinderUIFigure, 'push');
            app.FindRootButton.ButtonPushedFcn = createCallbackFcn(app, @FindRootButtonPushed, true);
            app.FindRootButton.Text = 'Find Root';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rootfinding

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RootFinderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RootFinderUIFigure)
        end
    end
end